classdef linear_array < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputVariablePanel             matlab.ui.container.Panel
        FrequencyEditFieldLabel        matlab.ui.control.Label
        freq                           matlab.ui.control.NumericEditField
        NumofElementsKEditFieldLabel   matlab.ui.control.Label
        K                              matlab.ui.control.NumericEditField
        DistofElementdEditFieldLabel   matlab.ui.control.Label
        dist                           matlab.ui.control.NumericEditField
        PlotButton                     matlab.ui.control.Button
        GHzLabel                       matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        AmplitudeTaperDropDownLabel    matlab.ui.control.Label
        AmplitudeTaperDropDown         matlab.ui.control.DropDown
        AmplitudeStepRatioEditFieldLabel  matlab.ui.control.Label
        AmplitudeStepRatioEditField    matlab.ui.control.NumericEditField
        SSLLabel                       matlab.ui.control.Label
        SSL                            matlab.ui.control.NumericEditField
        dBLabel                        matlab.ui.control.Label
        AntennaTypeDropDownLabel       matlab.ui.control.Label
        AntennaTypeDropDown            matlab.ui.control.DropDown
        EndfireoperationDropDownLabel  matlab.ui.control.Label
        EndfireoperationDropDown       matlab.ui.control.DropDown
        EndfireTypeDropDownLabel       matlab.ui.control.Label
        EndfireTypeDropDown            matlab.ui.control.DropDown
        degLabel                       matlab.ui.control.Label
        ImageBroadside                 matlab.ui.control.Image
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        PolarRadiationPatternPanel     matlab.ui.container.Panel
        BagasSatriyotomoLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            linear_array_code
            
        end

        % Value changed function: AmplitudeStepRatioEditField, 
        % AmplitudeTaperDropDown
        function AmplitudeTapperDropDownValueChanged(app, event)
            value = app.AmplitudeTaperDropDown.Value;
            if strcmp('Triangular',value)
                app.AmplitudeStepRatioEditFieldLabel.Visible = 'on';
                app.AmplitudeStepRatioEditField.Visible = 'on';
                app.SSLLabel.Visible = 'off';
                app.SSL.Visible = 'off';
                app.dBLabel.Visible = 'off';
            elseif strcmp('Dolph-Chebyshev',value)
                app.SSLLabel.Visible = 'on';
                app.SSL.Visible = 'on';
                app.dBLabel.Visible = 'on';
                app.AmplitudeStepRatioEditFieldLabel.Visible = 'off';
                app.AmplitudeStepRatioEditField.Visible = 'off';
            else
                app.AmplitudeStepRatioEditFieldLabel.Visible = 'off';
                app.AmplitudeStepRatioEditField.Visible = 'off';
                app.SSLLabel.Visible = 'off';
                app.SSL.Visible = 'off';
                app.dBLabel.Visible = 'off';
            end
        end

        % Value changed function: AntennaTypeDropDown
        function AntennaTypeDropDownValueChanged(app, event)
            value = app.AntennaTypeDropDown.Value;
            if strcmp('Broadside',value)
                app.AmplitudeTaperDropDownLabel.Visible = 'on';
                app.AmplitudeTaperDropDown.Visible = 'on';
                app.EndfireTypeDropDownLabel.Visible = 'off';
                app.EndfireTypeDropDown.Visible = 'off';
                app.EndfireoperationDropDownLabel.Visible = 'off';
                app.EndfireoperationDropDown.Visible = 'off';
                app.degLabel.Visible = 'off';

            elseif strcmp('Endfire',value)
                app.AmplitudeTaperDropDownLabel.Visible = 'off';
                app.AmplitudeTaperDropDown.Visible = 'off';
                app.SSLLabel.Visible = 'off';
                app.SSL.Visible = 'off';
                app.dBLabel.Visible = 'off';
                app.AmplitudeStepRatioEditFieldLabel.Visible = 'off';
                app.AmplitudeStepRatioEditField.Visible = 'off';
                app.EndfireTypeDropDownLabel.Visible = 'on';
                app.EndfireTypeDropDown.Visible = 'on';
                app.EndfireoperationDropDownLabel.Visible = 'on';
                app.EndfireoperationDropDown.Visible = 'on';
                app.degLabel.Visible = 'on';

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputVariablePanel = uipanel(app.UIFigure);
            app.InputVariablePanel.Title = 'Input Variable';
            app.FrequencyEditFieldLabel = uilabel(app.InputVariablePanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.freq = uieditfield(app.InputVariablePanel, 'numeric');
            app.NumofElementsKEditFieldLabel = uilabel(app.InputVariablePanel);
            app.NumofElementsKEditFieldLabel.Text = 'Num. of Elements (K)';
            app.K = uieditfield(app.InputVariablePanel, 'numeric');
            app.DistofElementdEditFieldLabel = uilabel(app.InputVariablePanel);
            app.DistofElementdEditFieldLabel.Text = 'Dist. of Element (d)';
            app.dist = uieditfield(app.InputVariablePanel, 'numeric');
            app.PlotButton = uibutton(app.InputVariablePanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GHzLabel = uilabel(app.InputVariablePanel);
            app.GHzLabel.Text = 'GHz';
            app.Label = uilabel(app.InputVariablePanel);
            app.Label.Text = '𝜆';
            app.AmplitudeTaperDropDownLabel = uilabel(app.InputVariablePanel);
            app.AmplitudeTaperDropDownLabel.Visible = 'off';
            app.AmplitudeTaperDropDownLabel.Text = 'Amplitude Taper';
            app.AmplitudeTaperDropDown = uidropdown(app.InputVariablePanel);
            app.AmplitudeTaperDropDown.Items = {'Uniform', 'Triangular', 'Binomial', 'Dolph-Chebyshev'};
            app.AmplitudeTaperDropDown.ValueChangedFcn = createCallbackFcn(app, @AmplitudeTapperDropDownValueChanged, true);
            app.AmplitudeTaperDropDown.Visible = 'off';
            app.AmplitudeTaperDropDown.Value = 'Uniform';
            app.AmplitudeStepRatioEditFieldLabel = uilabel(app.InputVariablePanel);
            app.AmplitudeStepRatioEditFieldLabel.Visible = 'off';
            app.AmplitudeStepRatioEditFieldLabel.Text = 'Amplitude Step Ratio';
            app.AmplitudeStepRatioEditField = uieditfield(app.InputVariablePanel, 'numeric');
            app.AmplitudeStepRatioEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeTapperDropDownValueChanged, true);
            app.AmplitudeStepRatioEditField.Visible = 'off';
            app.SSLLabel = uilabel(app.InputVariablePanel);
            app.SSLLabel.Visible = 'off';
            app.SSLLabel.Text = 'SSL';
            app.SSL = uieditfield(app.InputVariablePanel, 'numeric');
            app.SSL.Visible = 'off';
            app.dBLabel = uilabel(app.InputVariablePanel);
            app.dBLabel.Visible = 'off';
            app.dBLabel.Text = 'dB';
            app.AntennaTypeDropDownLabel = uilabel(app.InputVariablePanel);
            app.AntennaTypeDropDownLabel.Text = 'Antenna Type';
            app.AntennaTypeDropDown = uidropdown(app.InputVariablePanel);
            app.AntennaTypeDropDown.Items = {'Broadside', 'Endfire'};
            app.AntennaTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaTypeDropDownValueChanged, true);
            app.AntennaTypeDropDown.Value = 'Broadside';
            app.EndfireoperationDropDownLabel = uilabel(app.InputVariablePanel);
            app.EndfireoperationDropDownLabel.Visible = 'off';
            app.EndfireoperationDropDownLabel.Text = 'Endfire operation';
            app.EndfireoperationDropDown = uidropdown(app.InputVariablePanel);
            app.EndfireoperationDropDown.Items = {'90', '-90'};
            app.EndfireoperationDropDown.Visible = 'off';
            app.EndfireoperationDropDown.Value = '90';
            app.EndfireTypeDropDownLabel = uilabel(app.InputVariablePanel);
            app.EndfireTypeDropDownLabel.Visible = 'off';
            app.EndfireTypeDropDownLabel.Text = 'Endfire Type';
            app.EndfireTypeDropDown = uidropdown(app.InputVariablePanel);
            app.EndfireTypeDropDown.Items = {'Ordinary', 'Hansen-Woodyard'};
            app.EndfireTypeDropDown.Visible = 'off';
            app.EndfireTypeDropDown.Value = 'Ordinary';
            app.degLabel = uilabel(app.InputVariablePanel);
            app.degLabel.Visible = 'off';
            app.degLabel.Text = 'deg';
            app.ImageBroadside = uiimage(app.UIFigure);
            app.ImageBroadside.ImageSource = 'broadside.png';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '2D Pattern')
            xlabel(app.UIAxes2, 'Angle Relative to Broadside (deg)')
            ylabel(app.UIAxes2, 'Magnitude(dB)')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, '3D Pattern')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.PolarRadiationPatternPanel = uipanel(app.UIFigure);
            app.PolarRadiationPatternPanel.Title = 'Polar Radiation Pattern';
            app.BagasSatriyotomoLabel = uilabel(app.UIFigure);
            app.BagasSatriyotomoLabel.Text = 'Bagas Satriyotomo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linear_array

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end